classdef COnCURApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GraphPlotPanel          matlab.ui.container.Panel
        UIAxes                  matlab.ui.control.UIAxes
        ErrorsOnlyCheckBox      matlab.ui.control.CheckBox
        ActionsPanel            matlab.ui.container.Panel
        CoursesListBoxLabel     matlab.ui.control.Label
        CoursesListBox          matlab.ui.control.ListBox
        PlotButton              matlab.ui.control.Button
        TaxonomyDropDownLabel   matlab.ui.control.Label
        TaxonomyDropDown        matlab.ui.control.DropDown
        ErrorTypesListBoxLabel  matlab.ui.control.Label
        ErrorTypesListBox       matlab.ui.control.ListBox
    end

    
    properties (Access = private)
        tProgram Program;
        tGraph KCGraph;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, tProgram)
            app.tProgram = tProgram;
            app.CoursesListBox.Items = {tProgram.atKCMs(1:(end - 2)).strCourseCode};
            app.CoursesListBox.Value = app.CoursesListBox.Items;
            app.ErrorTypesListBox.Items = KCM.GetProblemTypes();
            app.ErrorTypesListBox.Value = app.ErrorTypesListBox.Items;
            app.TaxonomyDropDown.Items = tProgram.tProgramKCM.astrTaxonomies;
            app.TaxonomyDropDown.ItemsData = 1:tProgram.tProgramKCM.iTaxonomyCount;
            app.CoursesListBox.FontSize = app.CoursesListBox.FontSize...
                *ParametersManager.PARAMS.fResolutionMult;
            app.TaxonomyDropDown.FontSize = app.TaxonomyDropDown.FontSize...
                *ParametersManager.PARAMS.fResolutionMult;
            app.ErrorsOnlyCheckBox.FontSize = app.ErrorsOnlyCheckBox.FontSize...
                *ParametersManager.PARAMS.fResolutionMult;
            app.PlotButton.FontSize = app.PlotButton.FontSize...
                *ParametersManager.PARAMS.fResolutionMult;
            app.ErrorTypesListBox.FontSize = app.ErrorTypesListBox.FontSize...
                *ParametersManager.PARAMS.fResolutionMult;
            drawnow;
            app.UIFigure.WindowState = 'maximized';
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            app.tGraph = app.tProgram.GetKCMByCourseCode(app.CoursesListBox.Value).ToKCGraph();
            bErrorsOnly = app.ErrorsOnlyCheckBox.Value;
            app.tGraph.bIllegalGraphOnly = bErrorsOnly;
            app.tGraph.iTaxonomyToPlot = app.TaxonomyDropDown.Value;
            app.tGraph.acatValidProblems = app.ErrorTypesListBox.Value;
            %
            % @SuppressWarnings("uiaxes.plot")
            app.tGraph.plot(app.UIAxes, app.tProgram);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.GraphPlotPanel = uipanel(app.UIFigure);
            app.GraphPlotPanel.Title = 'Graph Plot';
            app.UIAxes = uiaxes(app.GraphPlotPanel);
            title(app.UIAxes, 'Waiting for a plot...')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.ErrorsOnlyCheckBox = uicheckbox(app.GraphPlotPanel);
            app.ErrorsOnlyCheckBox.Text = {'Show Structural Problems Only'; ''};
            app.ActionsPanel = uipanel(app.UIFigure);
            app.ActionsPanel.Title = 'Actions';
            app.CoursesListBoxLabel = uilabel(app.ActionsPanel);
            app.CoursesListBoxLabel.Text = 'Courses';
            app.CoursesListBox = uilistbox(app.ActionsPanel);
            app.CoursesListBox.Multiselect = 'on';
            app.CoursesListBox.Value = {'Item 1', 'Item 2'};
            app.PlotButton = uibutton(app.ActionsPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.TaxonomyDropDownLabel = uilabel(app.ActionsPanel);
            app.TaxonomyDropDownLabel.Enable = 'off';
            app.TaxonomyDropDownLabel.Visible = 'off';
            app.TaxonomyDropDownLabel.Text = 'Taxonomy';
            app.TaxonomyDropDown = uidropdown(app.ActionsPanel);
            app.TaxonomyDropDown.Enable = 'off';
            app.TaxonomyDropDown.Visible = 'off';
            app.ErrorTypesListBoxLabel = uilabel(app.ActionsPanel);
            app.ErrorTypesListBoxLabel.Text = 'Error Types';
            app.ErrorTypesListBox = uilistbox(app.ActionsPanel);
            app.ErrorTypesListBox.Multiselect = 'on';
            app.ErrorTypesListBox.Value = {'Item 1'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = COnCURApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end